classdef cv_challenge_app_desinger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        RoomDefinitionTab           matlab.ui.container.Tab
        afterconfirmhasbeenhinusethekeyboardtomovearoundwforwarLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        selectvoregroundButton      matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        ImageDropDown               matlab.ui.control.DropDown
        confirmButton               matlab.ui.control.Button
        selectvanishingpointButton  matlab.ui.control.Button
        ImageAxes                   matlab.ui.control.UIAxes
        RoomTourTab                 matlab.ui.container.Tab
        BackButton                  matlab.ui.control.Button
        ResetButton_2               matlab.ui.control.Button
        ImageAxes_2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img % Description 自己添加的全局变量
        vanishing_point 
        rear_wall
        
        radial_lines
        polygons
        draw_point      %figure point of vanishing point
        vp_raw
        vp
        im_h
        im_w
        D_phys              % user choose 3D-spacial
        H_phys              % user choose
        D                   % depth in pixel-coordinates
        H                   % height in pixel coordinates
        W                   %calculte from rw_h,rw_w and H
        outputsize
        morphed_imgs
        corner_points

        % camera parameters
        camPos
        camTarget
        camUp

        %parameters for foreground
        % CurrentState
        PosFG1
        PosFG2
        PosFG3
        PosFG4
        Min_y_FG
        Min_x_FG
        Max_y_FG
        Max_x_FG
        img_FG
        Foreground
        VertexFG
        ObjFG
        roi_mask
        h

        %Images
        pngFiles
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 获取屏幕尺寸
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % 窗口大小
            windowWidth = app.UIFigure.Position(3); % 窗口宽度
            windowHeight = app.UIFigure.Position(4); % 窗口高度

            % 计算窗口左上角位置
            xLeft = (screenWidth - windowWidth) / 2;
            yBottom = (screenHeight - windowHeight) / 2;

            % 设置窗口位置和大小
            app.UIFigure.Position = [xLeft, yBottom, windowWidth, windowHeight];

            % Intro picture
            app.img = imread('intro.png');
            imshow(app.img, 'Parent', app.ImageAxes);
            % Fit the axes with the image
            ax = app.ImageAxes;
            ax.DataAspectRatioMode = 'manual';
            ax.DataAspectRatio = [1, 1, 1]; % Set x, y, z axis ratio to 1:1:1

            % Set the axes limits to the image size
            axis(app.ImageAxes, [0 size(app.img, 2) 0 size(app.img, 1)]);

            % Disable zoom: (image is supposed to stay static in window)
            disableDefaultInteractivity(ax);

            % Get png files from image folder
            %app.pngFiles = dir(fullfile(fileparts(mfilename('fullpath')), '*.png'));
            imagesFolder = fullfile(fileparts(mfilename('fullpath')), 'images');
            app.pngFiles = dir(fullfile(imagesFolder, '*.png'));


            % Populate the dropdown menu
            fileNames = {app.pngFiles.name};
            app.ImageDropDown.Items = [{'choose your picture..'},fileNames];
            app.ImageDropDown.Items{length(app.ImageDropDown.Items)} = 'browse...';

            % Ensure User can only push the correct buttons
            app.selectvanishingpointButton.Enable = "off";
            app.confirmButton.Enable = "off";
            app.selectvoregroundButton.Enable = "off";

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % cla(app.ImageAxes);
            % cla(app.ImageAxes_2);
            % app.ImageDropDown.Value = 'choose your picture..'; 
            % app.selectvoregroundButton.Enable = "on";
            % app.selectvanishingpointButton.Enable = "on";
            % clear app

           
            app.TabGroup.SelectedTab = app.RoomDefinitionTab;

            cla(app.ImageAxes);
            cla(app.ImageAxes_2);
            app.ImageDropDown.Value = 'choose your picture..'; 
            app.selectvoregroundButton.Enable = "on";
            app.selectvanishingpointButton.Enable = "on";
            % clear app

            app.img = imread('intro.png');
            imshow(app.img, 'Parent', app.ImageAxes);
            % Fit the axes with the image
            ax = app.ImageAxes;
            ax.DataAspectRatioMode = 'manual';
            ax.DataAspectRatio = [1, 1, 1]; % Set x, y, z axis ratio to 1:1:1

            % Set the axes limits to the image size
            axis(app.ImageAxes, [0 size(app.img, 2) 0 size(app.img, 1)]);

            % Disable zoom: (image is supposed to stay static in window)
            disableDefaultInteractivity(ax);
            app.selectvanishingpointButton.Enable = "off";
            app.confirmButton.Enable = "off";
            app.selectvoregroundButton.Enable = "off";
        end

        % Value changed function: ImageDropDown
        function ImageDropDownValueChanged2(app, event)

            value = app.ImageDropDown.Value;
            if strcmp(value, 'browse...')
                [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.tif;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.tif, *.bmp)'});
                if ischar(file)
                    % File selected
                    fullPath = fullfile(path, file);
                    app.ImageDropDown.Items = [app.ImageDropDown.Items(1:end-1), file, 'browse...'];
                    app.ImageDropDown.Value = file;

                    % Display the selected image
                    app.img = imread(fullPath);
                    app.selectvanishingpointButton.Enable = "on";
                    app.confirmButton.Enable = "off";
                    app.selectvoregroundButton.Enable = "on";
                else
                    % No file selected, revert to previous value
                    app.ImageDropDown.Value = app.ImageDropDown.Items{1};
                end
            elseif strcmp(value, 'choose your picture..')
                app.img = imread('intro.png');
            app.selectvanishingpointButton.Enable = "off";
            app.confirmButton.Enable = "off";
            app.selectvoregroundButton.Enable = "off";
            else
                app.img = imread(value);
            app.selectvanishingpointButton.Enable = "on";
            app.confirmButton.Enable = "off";
            app.selectvoregroundButton.Enable = "on";
            end
            if ~isempty(app.img)
                imshow(app.img, 'Parent', app.ImageAxes);
                % Fit the axes with the image
                ax = app.ImageAxes;
                ax.DataAspectRatioMode = 'manual';
                ax.DataAspectRatio = [1, 1, 1]; % Set x, y, z axis ratio to 1:1:1

                % Set the axes limits to the image size
                axis(app.ImageAxes, [0 size(app.img, 2) 0 size(app.img, 1)]);

                % Disable zoom: (image is supposed to stay static in window)
                disableDefaultInteractivity(ax);
            else
                cla(app.ImageAxes);  % Clear the axes content
            end

        end

        % Button pushed function: selectvanishingpointButton
        function selectvanishingpointButtonPushed(app, event)
            % 删除之前的消失点对象
            % if ~isempty(app.vanishing_point) && isvalid(app.vanishing_point)
            %     delete(app.vanishing_point);
            %     delete(app.vp);
            % else
            [app.im_h, app.im_w] = size(app.img, [1, 2]);
            app.vanishing_point = drawpoint(app.ImageAxes,'Visible', 'off');
            % get the coordinate of selected point.
            hold (app.ImageAxes,"on")
            app.vp = round(app.vanishing_point.Position);

            [app.vanishing_point, app.rear_wall] = select_rear_wall(app.img, app.vp(1), app.vp(2),app.ImageAxes);
            app.radial_lines = create_radial_lines(app.img, app.vanishing_point, app.rear_wall,app.ImageAxes);
            app.selectvanishingpointButton.Enable = "off";
            app.confirmButton.Enable = "on";
        end

        % Button pushed function: confirmButton
        function confirmButtonPushed(app, event)
            app.polygons = get_trapezoidal_polys(app.img, app.rear_wall, app.radial_lines);
            % %polygons(cell) to nurimical
            % app.polygons = cell2mat(app.polygons);
            % app.rw_h = abs(app.polygons{1}(2,1) - app.polygons{1}(2,4));
            % app.rw_w = abs(app.polygons{1}(1,2) - app.polygons{1}(1,1));

            verts = app.rear_wall.Vertices';
            wc = [verts(:, 1), verts(:, 4), verts(:, 3), verts(:, 2)];

            % Ask user for initial room-dimensions:
            prompt = {'Enter rear-wall height H: ','Enter distance to rear-wall D: '};
            dlgtitle = 'Enter Scene Dimensions';
            fieldsize = [1 45; 1 45];
            definput = {'6','10'};
            usr_in_dim = inputdlg(prompt, dlgtitle, fieldsize, definput);

            % default physical height and depth dimensions:
            app.H_phys = str2double(usr_in_dim{1});
            app.D_phys = str2double(usr_in_dim{2});

            % room-dimensions in pixel-coordinates
            app.H = wc(2,3) - wc(2,2);
            app.W = wc(1,2) - wc(1,1);
            app.D = app.H/app.H_phys*app.D_phys;    % transform to pixel-corrdinates
            
            % define wall-dimensions:
            recSize_rear_wall = [app.H, app.W];
            recSize_walls = [app.H, app.D];
            recSize_floor_ceil = [app.D, app.W];

            % transform trapezoidals to rectangles:
            rec1 = morph_to_rectangle(app.img, app.polygons{1}, recSize_rear_wall);
            rec2 = morph_to_rectangle(app.img, app.polygons{2}, recSize_walls);
            rec3 = morph_to_rectangle(app.img, app.polygons{3}, recSize_floor_ceil);
            rec4 = morph_to_rectangle(app.img, app.polygons{4}, recSize_walls);
            rec5 = morph_to_rectangle(app.img, app.polygons{5}, recSize_floor_ceil);

            app.morphed_imgs = {rec1, rec2, rec3, rec4, rec5};

            % define corner-mesh-points:
            [X1, Y1] = meshgrid([app.W/2, -app.W/2], [app.H, 0]);
            % Z1 = app.D.*ones(size(X1));
            Z1 = zeros(size(X1));
            mesh1 = {X1, Y1, Z1};

            [Z2, Y2] = meshgrid([-app.D, 0], [app.H, 0]);
            X2 = app.W/2.*ones(size(Z2));
            mesh2 = {X2, Y2, Z2};

            [X3, Z3] = meshgrid([app.W/2, -app.W/2], [-app.D, 0]);
            Y3 = app.H.*ones(size(X3));
            mesh3 = {X3, Y3, Z3};

            [Z4, Y4] = meshgrid([0, -app.D], [app.H, 0]);
            X4 = -app.W/2.*ones(size(Z4));
            mesh4 = {X4, Y4, Z4};

            [X5, Z5] = meshgrid([app.W/2, -app.W/2], [0, -app.D]);
            Y5 = zeros(size(X5));
            mesh5 = {X5, Y5, Z5};

            app.corner_points = {mesh1, mesh2, mesh3, mesh4, mesh5};

            % go to next tab:
            app.TabGroup.SelectedTab = app.RoomTourTab;

            % pack ax-limits in array:
            axLimits = [app.W, app.H, app.D];

            % project morphed-surfaces into 3-dimensions at respective positions:
            plot_room(app.morphed_imgs, app.corner_points, app.ImageAxes_2, axLimits);

            % set initial camera parameters:
            app.camPos = [0, axLimits(2)/2, -axLimits(3)*0.8];
            app.camTarget = [0, axLimits(2)/2, 0];
            app.camUp = [0, 1, 0];
            
            % start keyboard controlled room tour:
            walk_through_3D_plot(app.ImageAxes_2, axLimits, app);


        end

        % Button pushed function: selectvoregroundButton
        function selectvoregroundButtonPushed(app, event)
            % Button pushed function: buttonForeground
            
            % hold(app.ImageAxes,"on")
            app.h=drawpolygon(app.ImageAxes);
            % hold(app.ImageAxes,"on")
            % h.Visible = 'off';
            posFG=app.h.Position;
            x_floor = floor(posFG(:, 1));   % 向下取整 x 坐标
            y_floor = floor(posFG(:, 2));   % 向下取整 y 坐标
            posFG_floor = [x_floor, y_floor];

            % hold on
            % get corners
            % app.PosFG1=[posFG(1),posFG(2)];
            % app.PosFG2=[posFG(1)+posFG(3),posFG(2)];
            % app.PosFG3=[posFG(1)+posFG(3),posFG(2)+posFG(4)];
            % app.PosFG4=[posFG(1),posFG(2)+posFG(4)];
            % app.Min_y_FG=floor(posFG(2));
            % app.Max_y_FG=floor(posFG(2)+posFG(4));
            % app.Min_x_FG=floor(posFG(1));
            % app.Max_x_FG=floor(posFG(1)+posFG(3));
            % floor the variables
            [app.Foreground, app.img_FG]= maskBackground(app.img, posFG_floor);
        
            app.img = app.img_FG;
            imshow(app.img_FG, 'Parent', app.ImageAxes);
            
            % [app.VertexFG,app.ObjFG] = foregroundobj(app.img_FG,app.vanishing_point,app.polygons,app.h);

            app.selectvoregroundButton.Enable = "off";
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            cla(app.ImageAxes_2);
            app.TabGroup.SelectedTab = app.RoomDefinitionTab;

            % cla(app.ImageAxes);
            % cla(app.ImageAxes_2);
            % app.ImageDropDown.Value = 'choose your picture..'; 
            % app.selectvoregroundButton.Enable = "on";
            % app.selectvanishingpointButton.Enable = "on";
            % % clear app
            % 
            % app.img = imread('intro.png');
            % imshow(app.img, 'Parent', app.ImageAxes);
            % % Fit the axes with the image
            % ax = app.ImageAxes;
            % ax.DataAspectRatioMode = 'manual';
            % ax.DataAspectRatio = [1, 1, 1]; % Set x, y, z axis ratio to 1:1:1
            % 
            % % Set the axes limits to the image size
            % axis(app.ImageAxes, [0 size(app.img, 2) 0 size(app.img, 1)]);
            % 
            % % Disable zoom: (image is supposed to stay static in window)
            % disableDefaultInteractivity(ax);
            % app.selectvanishingpointButton.Enable = "off";
            % app.confirmButton.Enable = "off";
            % app.selectvoregroundButton.Enable = "off";
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)

            % pack ax-limits in array:
            axLimits = [app.W, app.H, app.D];

            % set initial camera parameters:
            app.camPos = [0, axLimits(2)/2, -axLimits(3)*0.8];
            app.camTarget = [0, axLimits(2)/2, 0];
            app.camUp = [0, 1, 0];

            % update camera:
            campos(app.ImageAxes_2, app.camPos);
            camtarget(app.ImageAxes_2, app.camTarget);
            camup(app.ImageAxes_2, app.camUp);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RoomDefinitionTab = uitab(app.TabGroup);
            app.RoomDefinitionTab.Title = 'Room Definition';
            app.ImageAxes = uiaxes(app.RoomDefinitionTab);
            app.ImageAxes.XLim = [0 10];
            app.ImageAxes.YLim = [0 10];
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.selectvanishingpointButton = uibutton(app.RoomDefinitionTab, 'push');
            app.selectvanishingpointButton.ButtonPushedFcn = createCallbackFcn(app, @selectvanishingpointButtonPushed, true);
            app.selectvanishingpointButton.Text = 'select vanishing point';
            app.confirmButton = uibutton(app.RoomDefinitionTab, 'push');
            app.confirmButton.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirmButton.Text = 'confirm';
            app.ImageDropDown = uidropdown(app.RoomDefinitionTab);
            app.ImageDropDown.Items = {'Browse...'};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged2, true);
            app.ImageDropDown.Value = 'Browse...';
            app.ResetButton = uibutton(app.RoomDefinitionTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.selectvoregroundButton = uibutton(app.RoomDefinitionTab, 'push');
            app.selectvoregroundButton.ButtonPushedFcn = createCallbackFcn(app, @selectvoregroundButtonPushed, true);
            app.selectvoregroundButton.Text = 'select voreground';
            app.Image = uiimage(app.RoomDefinitionTab);
            app.Image.ImageSource = 'more_about_camera_orientation_2.png';
            app.afterconfirmhasbeenhinusethekeyboardtomovearoundwforwarLabel = uilabel(app.RoomDefinitionTab);
            app.afterconfirmhasbeenhinusethekeyboardtomovearoundwforwarLabel.Text = {'after ''confirm'' has been hit, '; 'use the keyboard to move around:'; ''; '''w'': Move forward'; '''s'':  Move backward'; '''a'':  Move left'; '''d'':  Move right'; '''🠉'': Look up'; '''🠋'': Look down'; '''🠈'': Look left'; '''🠊'': Look right'; '''q'':  Roll left'; '''e'':  Roll right'; ''};
            app.RoomTourTab = uitab(app.TabGroup);
            app.RoomTourTab.Title = 'RoomTour';
            app.ImageAxes_2 = uiaxes(app.RoomTourTab);
            app.ImageAxes_2.XLim = [0 10];
            app.ImageAxes_2.YLim = [0 10];
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.YTick = [];
            app.ResetButton_2 = uibutton(app.RoomTourTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.BackButton = uibutton(app.RoomTourTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cv_challenge_app_desinger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end